classdef OCTAVA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OCTAVAUIFigure                 matlab.ui.Figure
        SavingMenu                     matlab.ui.container.Menu
        SaveBinaryMapMenu              matlab.ui.container.Menu
        SaveMetricsasmatFileMenu       matlab.ui.container.Menu
        SaveHistogramDataMenu          matlab.ui.container.Menu
        SaveSessionMenu                matlab.ui.container.Menu
        ClearSessionMenu               matlab.ui.container.Menu
        ImageJMenu                     matlab.ui.container.Menu
        StartImageJMenu                matlab.ui.container.Menu
        QuitImageJMenu                 matlab.ui.container.Menu
        OCTAVAMenu                     matlab.ui.container.Menu
        ClearPlotsMenu                 matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ShowOCTAVAhelpMenu             matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        Frangisigma_ubSpinnerLabel     matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        MeantortuosityLabel            matlab.ui.control.Label
        FractaldimensionLabel          matlab.ui.control.Label
        TotalvessellengthLabel         matlab.ui.control.Label
        BranchpointdensityLabel        matlab.ui.control.Label
        MediandiameterLabel            matlab.ui.control.Label
        MeandiameterLabel              matlab.ui.control.Label
        VessellengthdensityLabel       matlab.ui.control.Label
        VesselareadensityLabel         matlab.ui.control.Label
        MetricsLabel                   matlab.ui.control.Label
        ImagesizemmLabel               matlab.ui.control.Label
        RealImageSizemm                matlab.ui.control.NumericEditField
        Frangisigma_ubSpinner          matlab.ui.control.Spinner
        Frangisigma_lbSpinner          matlab.ui.control.Spinner
        Frangisigma_lbSpinnerLabel     matlab.ui.control.Label
        ImagesubsetButton              matlab.ui.control.Button
        ProcessSingleImageLabel        matlab.ui.control.Label
        ReadyLabel                     matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        ResizeButton                   matlab.ui.control.Button
        binsizeTort                    matlab.ui.control.Spinner
        binsizeLength                  matlab.ui.control.Spinner
        binsizeDiams                   matlab.ui.control.Spinner
        BinsizeforhistogramsLabel      matlab.ui.control.Label
        kernelSize                     matlab.ui.control.Spinner
        MedfiltCheckBox                matlab.ui.control.CheckBox
        LoadimageButton                matlab.ui.control.Button
        CloseImageJwindowsButton       matlab.ui.control.Button
        savefileLabel_single           matlab.ui.control.Label
        SelectSaveFolderButton_single  matlab.ui.control.Button
        BiomarkersButton               matlab.ui.control.Button
        TwigsizeSlider                 matlab.ui.control.Slider
        TwigsizeSliderLabel            matlab.ui.control.Label
        ManualcurationCheckBox         matlab.ui.control.CheckBox
        SkeletonizeButton              matlab.ui.control.Button
        InvertButton                   matlab.ui.control.Button
        FrangiBool                     matlab.ui.control.CheckBox
        BinarizeButton                 matlab.ui.control.Button
        BinarizationmethodDropDownLabel  matlab.ui.control.Label
        BinarizationmethodDropDown     matlab.ui.control.DropDown
        Panel_2                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        BatchProcessingLabel           matlab.ui.control.Label
        bachsavefileLabel              matlab.ui.control.Label
        bachfileLabel                  matlab.ui.control.Label
        SavefolderButton               matlab.ui.control.Button
        RawdatafolderButton            matlab.ui.control.Button
        FiletypeDropDownLabel          matlab.ui.control.Label
        FiletypeDropDown               matlab.ui.control.DropDown
        SavebinaryimagesCheckBox       matlab.ui.control.CheckBox
        StartbatchprocessButton        matlab.ui.control.Button
        FileLabel                      matlab.ui.control.Label
        inputImageAxes                 matlab.ui.control.UIAxes
        BinaryImageAxes                matlab.ui.control.UIAxes
        SkelImageAxes                  matlab.ui.control.UIAxes
        OverlayImageAxes               matlab.ui.control.UIAxes
        DiamsAxes                      matlab.ui.control.UIAxes
        TortsAxes                      matlab.ui.control.UIAxes
        LengthsAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %This section initialzes global variables
        homedir %Home directory of OCTAVA app
        macroBasePath %Folder with all ImageJ Macros
        MacroFile %Name of OCTAVA macro file for ImageJ
        inputImage % Input image for processing
        fullImSizePix %Keeps track of the size of the full image so that pix/mm isn't changed when subset is collected. 
        BinarizationMethod % Selected Binarization Method to use  
        BinaryImage % Binarized Image
        FileLabelName %Name of filebeing processed
        resultsPath %Path to results folder
        biomarkers %Structural array containing biomarkers
        biomarkers_cumulative %collects all the biomarkerd for each dataset run in a given session 
        biomarkers_counter %Keeps count of number of datasets processed in a session 
        batchFile %Path to data folder for batch processing 
        batchSaveFile %Path to folder for saving batch binarized images
        singleSaveFile %Path to folder for saving individual datasets 
        csvFile % Holds fileame to keep track of image number 
        lastPath %keeps track of the last folder open when selecting save files 
        skelReady %checks if image has been sent to imageJ
        imgShape %notes if image is square or rectangular for area calculation 
        imgSkel %Stores the skelton image for calculation of fractal dimension
        imgOriginal %Stores orignial input image in case image cropping is applied to avoid boundary effects
    end
    
    methods (Access = private)
        
        function loadImage(app)


            [Filename, Pathname] = uigetfile(fullfile(app.lastPath,'*.*'), 'Select MIP');
            
            if Filename == 0
                warndlg('You did not choose an image file') ; 
                app.inputImage = [];
            else
                a = fullfile(Pathname, Filename);
                a = imread(a);
                if size(a,3) == 3
                    a = rgb2gray(a);
                end
                app.inputImage = double(int16(squeeze(a(:,:,1))));
            
            
                imshow( app.inputImage, 'Parent', app.inputImageAxes);
                app.inputImageAxes.CLim = [0 255];
                app.lastPath = Pathname; 
                
                inds = strfind(Filename,'.');
                app.FileLabelName = Filename(1:inds(end)-1);
                app.FileLabel.Text = app.FileLabelName;
                
                app.fullImSizePix = size(app.inputImage); 
                app.imgShape = 'rect'; %Initialize image shape to rectange
                app.imgOriginal = app.inputImage; %Stores originial image to avoid boundary effect of binairaztion on cropped images 

            end


        end
        
        function binarizeImage(app)
            %%Load info 
            Method = app.BinarizationmethodDropDown.Value; %Check selected binarization method 
            
            
            %% %% Make sure vessels are white and the rest is black 
            [y] = max(app.inputImage);
            [~,cc] = max(y);
            [~,rr] = max(app.inputImage(:,cc));
            
            %%Apply Median filter if selected 
            if app.MedfiltCheckBox.Value
                Im = medfilt2(app.inputImage,[app.kernelSize.Value app.kernelSize.Value]);
            else
                Im = app.inputImage;
            end
            
            %% Binarize 
            if app.FrangiBool.Value
                opts.sigmarange = [1 app.Frangisigma_lbSpinner.Value];
                opts.sigmastepsize=2;
                opts.correctionconst1=0.8;
                opts.correctionconst2=15;
                Im_lb=frangi_2Dfilter(Im,opts);

                opts.sigmarange = [1 app.Frangisigma_ubSpinner.Value];
                Im_ub=frangi_2Dfilter(Im,opts);
            end
            
            if strcmp(Method,'Fuzzy means')
                %Implements Fuzzy thrshold binarization 
                %Number of sets;
                nth=2;
                Im_lb_fuz=fuzzy_thresholding(Im_lb,nth,3)-1;  
                Im_ub_fuz=fuzzy_thresholding(Im_ub,nth,3)-1; 
            elseif strcmp(Method,'Adaptive thresholding')
                %Implements adaptive threshold binarization
                app.BinaryImage= double(adaptivethresholding(Im,70));
                %Maybe make  the number here edjustable? 
            else
                warndlg('Incorrect segmentation method') ;
            end
            
            Im_lb_fuz(Im_lb_fuz==Im_lb_fuz(rr,cc)) = 255;
            Im_lb_fuz(Im_lb_fuz~=Im_lb_fuz(rr,cc)) = 0;
            Im_ub_fuz(Im_ub_fuz==Im_ub_fuz(rr,cc)) = 255;
            Im_ub_fuz(Im_ub_fuz~=Im_ub_fuz(rr,cc)) = 0;

            Im_adaptive = imbinarize(mat2gray(app.inputImage),'adaptive','Sensitivity',0.55);
            
            app.BinaryImage = double((Im_adaptive.*Im_ub_fuz)|Im_lb_fuz);
            app.BinaryImage(app.BinaryImage==app.BinaryImage(rr,cc)) = 255;
            app.BinaryImage(app.BinaryImage~=app.BinaryImage(rr,cc)) = 0;
                        
        end
        
        
        function generateMetrics(app,Batch)
            if Batch
            dir = app.batchSaveFile;
            else
                dir = app.singleSaveFile;
            end
        
            app.biomarkers.name = app.FileLabelName;
            app.csvFile = char(app.csvFile);
            if ~(app.csvFile(end) == 'v')
                app.csvFile = app.csvFile(1:end-1);
            end
        
        
            [Nx, Ny] = size(app.inputImage); %size of the image in pixels 
            pix_to_mm = app.RealImageSizemm.Value/app.fullImSizePix(1);  %This assumes images have equal spatial sampling 
            % (this must be true for accurate readings and the image must be resized if not)   
            %Calcuate image area based on the actual image used for after cropping  
            if strcmp(app.imgShape,'rect')
                imgArea_mm = Nx*Ny*pix_to_mm^2; %This will now be correct for images that are rectangular 
                imgArea_pix = Nx*Ny;
            else
                imgArea_mm = pi*(Nx/2)^2*pix_to_mm^2; %This will now be correct for images that are circular 
                imgArea_pix = pi*(Nx/2)^2;
            end


            %LoadDiameterTable - Loads the diameter, length, and tortuosity
            %of each identified vessel in the image 
            warning('off')
            DiamTableFile = [dir '/CSVs/' app.csvFile];
            DiamTable = readtable(DiamTableFile); 
            app.biomarkers.Diams = round(rmmissing(DiamTable.AverageThickness*pix_to_mm*1000),1); %Diameters (in microns)
            app.biomarkers.Tort = rmmissing(DiamTable.Tortuosity-1); %Tortuosity
            app.biomarkers.Lengths = rmmissing(DiamTable.AdjustedLen)*pix_to_mm; %In mm
            warning('on')
        

            %% Calulate metrics
            
            %Vessel Area Density 
            app.biomarkers.VAD = round(sum(sum(app.BinaryImage))/255/imgArea_pix,2)*100;
            
            %Vessel Length Density
            app.biomarkers.totalLength = round(sum(app.biomarkers.Lengths),2); %In mm
            app.biomarkers.VLD = round(app.biomarkers.totalLength*pix_to_mm/imgArea_mm,4)*100; %in percent
            
            %Mean Diameter
            app.biomarkers.MeanDiam = round(mean(app.biomarkers.Diams)); %In microns
            app.biomarkers.MedianDiam = round(median(app.biomarkers.Diams)); 
            
            %Branchpoint density
            app.biomarkers.BD =  round(str2double(app.biomarkers.nodes)/app.biomarkers.totalLength,2); %in 1/mm
            
            %Mean toruisity
            app.biomarkers.mTort = round(mean(app.biomarkers.Tort),2);
            
            %Fractal dimension - box counting method 
            img = logical(imcomplement(app.imgSkel));
            Nx = max(size(img));
            numIterations = ceil(log10(Nx)/log10(2));
            width = 2^numIterations;
            %pad img with zeros so that it is square with lengths = to a power of 2 
            temp = zeros(width,width);
            temp(1:size(img,1), 1:size(img,2)) = img; 
            img = temp;
            clear temp 
            n_r = zeros(1,numIterations+1); %Initialize variable for counts 
            
            %Perform box counting 
            n_r(numIterations+1) = sum(sum(img));
            
            for ii = (numIterations-1):-1:0
                boxSize = 2^(numIterations-ii);
                boxRad = round(boxSize/2);
                
                for jj = 1:boxSize:(width-boxSize+1)
                    for kk = 1:boxSize:(width-boxSize+1)
                         img(jj,kk) = ( img(jj,kk) || img(jj+boxRad,kk) || img(jj,kk+boxRad) || img(jj+boxRad,kk+boxRad) );
                    end
                end
                
                 n_r(ii+1) = sum(sum(img(1:boxSize:(width-boxSize+1),1:boxSize:(width-boxSize+1))));
            end
            
            n_r = n_r(end:-1:1);
            r = 2.^(0:numIterations);
            slope = -gradient(log10(n_r))./gradient(log10(r));
            app.biomarkers.FD = round(mean(slope),2);
            app.biomarkers.FDerror = round(std(slope),2);


            %Display results - caluclulated biomarkers
            app.VesselareadensityLabel.Text = ['Vessel area density = ' num2str(app.biomarkers.VAD) '%']; 
            app.VessellengthdensityLabel.Text = ['Vessel length density = ' num2str(app.biomarkers.VLD) '%'];
            app.TotalvessellengthLabel.Text = ['Total vessel length = ' num2str(app.biomarkers.totalLength) ' mm']; 
            app.MeandiameterLabel.Text = ['Mean diameter = ' num2str(app.biomarkers.MeanDiam) ' ' char(181) 'm'];
            app.MediandiameterLabel.Text = ['Median diameter = ' num2str(app.biomarkers.MedianDiam) ' ' char(181) 'm'];
            app.BranchpointdensityLabel.Text = ['Branchpoint density = ' num2str(app.biomarkers.BD) ' (1/mm)'];
            app.FractaldimensionLabel.Text = ['Fractal dimension = ' num2str(app.biomarkers.FD) ' +/- ' num2str(app.biomarkers.FDerror)];
            app.MeantortuosityLabel.Text = ['Mean tortuosity = ' num2str(app.biomarkers.mTort)];
            
            %Generate histograms of diameter, tortuosity 
            %cens_diams = linspace(0,300,31);  
            %dHist = hist(Diams,cens_diams);
            %bar(cens_diams,dHist,'Parent',app.DiamsAxes)
            %Will using histogram vs hist cause an issue with non-uniform
            %bin size between datasets? Need to check this! 
            if ~Batch
                histogram(app.biomarkers.Diams,'BinWidth',app.binsizeDiams.Value,'Parent',app.DiamsAxes);
                histogram(app.biomarkers.Tort,'BinWidth',app.binsizeTort.Value,'Parent', app.TortsAxes);
                histogram(app.biomarkers.Lengths,'BinWidth',app.binsizeLength.Value,'Parent', app.LengthsAxes);
            end
            
            app.biomarkers_counter = app.biomarkers_counter+1;
            app.biomarkers_cumulative(app.biomarkers_counter).name = app.biomarkers.name;
            app.biomarkers_cumulative(app.biomarkers_counter).nodes = str2double(app.biomarkers.nodes);
            app.biomarkers_cumulative(app.biomarkers_counter).VAD = app.biomarkers.VAD;
            app.biomarkers_cumulative(app.biomarkers_counter).totalLength = app.biomarkers.totalLength;
            app.biomarkers_cumulative(app.biomarkers_counter).VLD = app.biomarkers.VLD;
            app.biomarkers_cumulative(app.biomarkers_counter).MeanDiam = app.biomarkers.MeanDiam;
            app.biomarkers_cumulative(app.biomarkers_counter).MedianDiam = app.biomarkers.MedianDiam;
            app.biomarkers_cumulative(app.biomarkers_counter).BD = app.biomarkers.BD;
            app.biomarkers_cumulative(app.biomarkers_counter).FD = app.biomarkers.FD;
            app.biomarkers_cumulative(app.biomarkers_counter).FDerror = app.biomarkers.FDerror;
            app.biomarkers_cumulative(app.biomarkers_counter).mTort = app.biomarkers.mTort;
            
            if app.FrangiBool.Value
                app.biomarkers_cumulative(app.biomarkers_counter).Frangi_sigma_max = app.Frangisigma_lbSpinner.Value;
            else 
                app.biomarkers_cumulative(app.biomarkers_counter).Frangi_sigma_max = 0;
            end
            if app.MedfiltCheckBox.Value
                app.biomarkers_cumulative(app.biomarkers_counter).MedFilt_Kernel = app.kernelSize.Value;
            else
                app.biomarkers_cumulative(app.biomarkers_counter).MedFilt_Kernel = 0;
            end
            app.biomarkers_cumulative(app.biomarkers_counter).Binarization = app.BinarizationmethodDropDown.Value;


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off')       
            %Establish path to app for loading dependency files
            appdir = mfilename('fullpath');
            inds = strfind(appdir,'\')';
            
            if isempty(inds)
                %This should help with mac compatability 
                inds = strfind(appdir,'/')';
            end
            app.homedir = appdir(1:inds(end));
            app.lastPath = app.homedir; 
            
            %Add MIJI/ MATLAB-ImageJ to Javapath
            mijPath = fullfile(app.homedir,'Imagej/mij.jar');
            javaaddpath (mijPath);
            %mijPath = fullfile(app.homedir,'Imagej/imagej-matlab-0.7.2.jar');
            %javaaddpath (mijPath);
            
            %load dpendency stripts 
            scriptPath = fullfile(app.homedir,'Scripts');
            JscriptPath = fullfile(app.homedir,'ImageJ/Fiji/scripts');
            addpath(scriptPath);
            addpath(JscriptPath);
            
            %Setup Path to ImageJ Macros 
            app.macroBasePath = fullfile(app.homedir,'ImageJ/Fiji/macros/OCTAVA tools');
            app.MacroFile = 'OCTAVA.ijm';
            
            %Initialize save path to home directory 
            app.resultsPath = fullfile(app.homedir,'Results');
            app.singleSaveFile = app.homedir; 
            app.savefileLabel_single.Text = app.singleSaveFile;
            app.batchFile = app.homedir;
            app.batchSaveFile = app.homedir;
            
            %Initialize dataset counter and setup to record all biomarks
            %from session 
            app.biomarkers_counter = 0; 
            app.biomarkers_cumulative = struct('name','filename','nodes',0,'VAD',0,'totalLength',0,'VLD',0,'MeanDiam',0,'MedianDiam',0,'BD',0,'FD',0,'FDerror',0, 'mTort', 0, 'Frangi_sigma_max',0,'MedFilt_Kernel',0,'Binarization','Method');


            %Start ImageJ
            Miji;
            app.skelReady = 0;
            
            warning('on') 
            
        end

        % Menu selected function: StartImageJMenu
        function StartImageJMenuSelected(app, event)
            app.ReadyLabel.Text = 'Wait'; %sets wait label 
            app.ReadyLamp.Color = [1.00,0.00,0.00];
            drawnow
            Miji;
            app.ReadyLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0.00,1.00,0.00];
        end

        % Menu selected function: QuitImageJMenu
        function QuitImageJMenuSelected(app, event)
            ij.IJ.run("Quit","");
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            app.ReadyLabel.Text = 'Wait';
            app.ReadyLamp.Color = [1.00,0.00,0.00];
            drawnow
            loadImage(app)
            app.inputImageAxes.Colormap = gray; 
            app.ReadyLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0.00,1.00,0.00];
       
        end

        % Callback function: BinarizationmethodDropDown, BinarizeButton, 
        % ...and 1 other component
        function BinarizeButtonPushed(app, event)
            if isempty(app.inputImage)
                warndlg('You did not choose an image file') ; 
                cla(app.BinaryImageAxes);
            else
              app.ReadyLabel.Text = 'Wait';
                app.ReadyLamp.Color = [1.00,0.00,0.00];
                drawnow
                binarizeImage(app);
                imshow( app.BinaryImage, 'Parent', app.BinaryImageAxes);
                app.BinaryImageAxes.Colormap = gray;
                app.BinaryImageAxes.CLim = [0 255];
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0.00,1.00,0.00]; 
                app.skelReady = 1;
            end
            
        end

        % Menu selected function: SaveBinaryMapMenu
        function SaveBinaryMapMenuSelected(app, event)
            [file,path] = uiputfile([app.lastPath '/*.tif']);
            f = fullfile(path,file);
            imwrite(app.BinaryImage,f)
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            %Inverts the colormap of the binary mask
            app.BinaryImage(app.BinaryImage == 0) = 1;
            app.BinaryImage(app.BinaryImage == 255) = 0;
            app.BinaryImage(app.BinaryImage == 1) = 255;
            imagesc( app.BinaryImage, 'Parent', app.BinaryImageAxes);
        end

        % Button pushed function: SkeletonizeButton
        function SkeletonizeButtonPushed(app, event)
            if ~app.skelReady
                if isempty(app.inputImage)
                    warndlg('You did not choose an image file') ; 
                else
                    warndlg('Binary mask required for skeletonization') ;  
                end
            else
                app.ReadyLabel.Text = 'Wait'; %sets wait label 
                app.ReadyLamp.Color = [1,0,0];
                drawnow
                
                %Close all current windows in ImageJ
                MIJ.closeAllWindows;
                1
                %Send input imgage to ImageJ
                img = copytoImgPlus(uint8(app.inputImage), [1 1], [app.FileLabelName '-original']);
                net.imglib2.img.display.imagej.ImageJFunctions.show(img);
                2
                %Send binary mask to ImageJ
                img = copytoImgPlus(uint8(app.BinaryImage), [1 1], [app.FileLabelName]);
                net.imglib2.img.display.imagej.ImageJFunctions.show(img);
                clear img
                3
                
                IJ = ij.IJ();
                args = strcat('fname=', app.FileLabelName, 'Savedir=',app.singleSaveFile,'/','Manual=',num2str(app.ManualcurationCheckBox.Value), 'TwigSize=', app.TwigsizeSlider.Value);
                macro_path = fullfile(app.macroBasePath, app.MacroFile);             
                IJ.runMacroFile(java.lang.String(macro_path),java.lang.String(args)); %Runs skeletenization in imageJ
                %Display skeleton and overlay in GUI
                4
                imglist = MIJ.getListImages;
    
                for i = 1:length(imglist)
                    str = imglist(i).toCharArray';
                    if contains(str,'Skeleton')
                        skelname = str;
                    end
                    if contains(str,'Overlay')
                        ovrlyname = str;
                    end
                end
               5
                %Show skeleton 
                delete(get(app.SkelImageAxes,'Children'))
                app.imgSkel = imcomplement(MIJ.getImage(skelname));
                [Ny, Nx] = size(app.inputImage);
                a = imresize(app.imgSkel,[round(Ny/2),round(Nx/2)]);
                a(a==-1)=255;
                imshow( a, 'Parent', app.SkelImageAxes);
                app.SkelImageAxes.Colormap = gray; 
                app.SkelImageAxes.CLim = [0 255];             
                app.SkelImageAxes.DataAspectRatio = [1 1 1];
                6
                %Show overlay
                delete(get(app.OverlayImageAxes,'Children'))
                a = uint8(MIJ.getImage(ovrlyname));
                imshow( a, 'Parent', app.OverlayImageAxes);
                
                IJlog = char(MIJ.getLog());
                inds = strfind(IJlog,app.FileLabelName);
                app.csvFile = IJlog(inds(end):end);
                app.biomarkers.nodes = IJlog(1:inds(end)-1);
                
                MIJ.closeAllWindows;
                
                app.ReadyLabel.Text = 'Ready';
                app.ReadyLamp.Color = [0 1 0];
                app.skelReady = 3; 
            end
        end

        % Button pushed function: BiomarkersButton
        function BiomarkersButtonPushed(app, event)
          if app.skelReady ~= 3
              warndlg('Vessel Analysis results not yet available') ; 
          else
              app.ReadyLabel.Text = 'Wait';
              app.ReadyLamp.Color = [1.00,0.00,0.00];
              drawnow
              app.biomarkers.name = app.FileLabelName;
              Batch = false;
              generateMetrics(app,Batch)
              app.ReadyLabel.Text = 'Ready';
              app.ReadyLamp.Color = [0.00,1.00,0.00];
          end
        end

        % Button pushed function: SelectSaveFolderButton_single
        function SelectSaveFolderButton_singlePushed(app, event)
            [app.singleSaveFile] = uigetdir(app.singleSaveFile);
            app.savefileLabel_single.Text = app.singleSaveFile;
            app.resultsPath = fullfile(app.singleSaveFile,'Results');
            if ~exist(app.resultsPath, 'dir')
                mkdir(app.resultsPath)
            end
            app.lastPath = app.singleSaveFile;
        end

        % Menu selected function: SaveMetricsasmatFileMenu
        function SaveMetricsasmatFileMenuSelected(app, event)
            [file,path] = uiputfile(fullfile(app.lastPath,'*.mat'));
            f = fullfile(path,file);
  
            if path ~=0
                app.lastPath=path;
            end
%             savedir = [app.resultsPath '/'];
             name = erase(app.FileLabelName,' ');
             eval([name ' =  app.biomarkers;']);
%             fname = [savedir name '_biomarkers.mat'];
%             ctr = 0;
%             while exist(fname,'file')
%                 ctr = ctr+1;
%                 fname = [savedir name '_biomarkers-' num2str(ctr) '.mat'];
%             end
% 
%             save(fname,name);
              save(f,name)
        end

        % Menu selected function: SaveSessionMenu
        function SaveSessionMenuSelected(app, event)
            savedir = uigetdir(app.lastPath);  
            if savedir ~=0
                app.lastPath=savedir;
            end
            datatable = struct2table(app.biomarkers_cumulative);
            t = datestr(datetime);
            t = erase(t,':')';
            ExcelFilename = [savedir '/OCTAVA Results ' t' '.csv'];
            writetable(datatable,ExcelFilename);
        end

        % Menu selected function: ClearSessionMenu
        function ClearSessionMenuSelected(app, event)
            %Reinitializes variables for saving session results 
            app.biomarkers_counter = 0; 
            app.biomarkers_cumulative = struct('name','filename','nodes',0,'VAD',0,'totalLength',0,'VLD',0,'MeanDiam',0,'MedianDiam',0,'BD',0,'FD',0,'FDerror',0, 'mTort', 0, 'Frangi_sigma_max',0,'MedFilt_Kernel',0,'Binarization','Method');
        end

        % Menu selected function: ShowOCTAVAhelpMenu
        function ShowOCTAVAhelpMenuSelected(app, event)
            helpfile = fullfile(app.homedir,'OCTAVA_Readme.txt');
            open(helpfile)
        end

        % Button pushed function: RawdatafolderButton
        function RawdatafolderButtonPushed(app, event)
            app.batchFile = uigetdir;
            app.bachfileLabel.Text = app.batchFile;
        end

        % Button pushed function: SavefolderButton
        function SavefolderButtonPushed(app, event)
            app.batchSaveFile = uigetdir(app.batchSaveFile);
            app.bachsavefileLabel.Text = app.batchFile;
        end

        % Button pushed function: StartbatchprocessButton
        function StartbatchprocessButtonPushed(app, event)
           %Set wait label; 
           Batch=true; 
           app.ReadyLabel.Text = 'Wait';
           app.ReadyLamp.Color = [1.00,0.00,0.00];
           drawnow
           %Setup variables for saving biomarkers 
            app.biomarkers_counter = 0; 
            app.biomarkers_cumulative = struct('name','filename','nodes',0,'VAD',0,'totalLength',0,'VLD',0,'MeanDiam',0,'MedianDiam',0,'BD',0,'FD',0,'FDerror',0, 'mTort', 0, 'Frangi_sigma_max',0,'MedFilt_Kernel',0,'Binarization','Method');
           %Generate list of all files in the directory
            filetype = app.FiletypeDropDown.Value;
            
            if strcmp(filetype,'binary mask')
                filetype = 'tif';
                prebin = 1;
            else 
                prebin = 0;
                
            end
            list = dir(fullfile(app.batchFile,['*.' filetype]));
            numfiles = size(list,1);


            for nn = 1:length(list)
                MIJ.closeAllWindows;
                app.Panel_2.Title = strcat("Batch Processing: ",  num2str(nn), ' of  ', num2str(numfiles));
                drawnow
                %For each file in the directory 
                file = list(nn).name;
                c = strfind(file,'.');
                app.FileLabelName = file(1:c(end)-1);
                file = fullfile(app.batchFile,file);
                app.inputImage = imread(file);
                app.inputImage = double(int16(squeeze(app.inputImage(:,:,1))));
                app.fullImSizePix = size(app.inputImage); 
                %Binarize Image
                if prebin
                    app.BinaryImage = app.inputImage;
                else
                    binarizeImage(app);
                end
                    
                %Save binary image if requested
                if app.SavebinaryimagesCheckBox.Value
                    if ~exist(fullfile(app.batchSaveFile,'BinaryImages'), 'dir')
                        mkdir(fullfile(app.batchSaveFile,'BinaryImages'))
                    end
                    savefilename = fullfile(app.batchSaveFile,'BinaryImages',[app.FileLabelName '.tif']);
                    imwrite(app.BinaryImage,savefilename,'tif','WriteMode','append','Compression','none','Description','none');
                end
                
                %Send to ImageJ OCT and Binary Images
                img = copytoImgPlus(uint8(app.inputImage), [1 1], [app.FileLabelName '-original']);
                net.imglib2.img.display.imagej.ImageJFunctions.show(img);
                %Send binary mask to ImageJ
                img = copytoImgPlus(uint8(app.BinaryImage), [1 1], [app.FileLabelName]);
                net.imglib2.img.display.imagej.ImageJFunctions.show(img);
                clear img
                
                %Run skeletonization of single image in ImageJ 
                IJ = ij.IJ();
                args = strcat('fname=', app.FileLabelName, 'Savedir=',app.batchSaveFile,'/','Manual=',num2str(app.ManualcurationCheckBox.Value),'TwigSize=', app.TwigsizeSlider.Value);
                macro_path = fullfile(app.macroBasePath, app.MacroFile);             
                IJ.runMacroFile(java.lang.String(macro_path),java.lang.String(args)); %Runs skeletenization in imageJ
                
                %Get filename for biomarkers processing 
                IJlog = char(MIJ.getLog());
                inds = strfind(IJlog,app.FileLabelName);
                app.csvFile = IJlog(inds(end):end);
                app.biomarkers.nodes = IJlog(1:inds(end)-1);
                
                imglist = MIJ.getListImages;
                for i = 1:length(imglist)
                    str = imglist(i).toCharArray';
                    if contains(str,'Skeleton')
                        skelname = str;
                    end
                end
                app.imgSkel = imcomplement(MIJ.getImage(skelname));
                
                %Generate Biomarkers and keep list of results 
                
                generateMetrics(app,Batch)
                
                MIJ.closeAllWindows;
            end
            
            if isempty(list)
                warndlg('No Images Selected') ;         
            else
                %Save Excel file with results summary 
                datatable = struct2table(app.biomarkers_cumulative);
                savedir = [app.batchSaveFile,'/'];
                t = datestr(datetime);
                t = erase(t,':')';
                ExcelFilename = [savedir 'OCTAVA Results ' t' '.csv'];
                writetable(datatable,ExcelFilename);
            end
            
          app.ReadyLabel.Text = 'Ready';
          app.ReadyLamp.Color = [0.00,1.00,0.00];
          app.Panel_2.Title = "Batch Processing";
        end

        % Button pushed function: CloseImageJwindowsButton
        function CloseImageJwindowsButtonPushed(app, event)
                MIJ.closeAllWindows;
        end

        % Close request function: OCTAVAUIFigure
        function OCTAVAUIFigureCloseRequest(app, event)

            sel = uiconfirm(app.OCTAVAUIFigure,'Are you sure you want to quit?', 'Quit', 'Options', {'Close OCTAVA Only', 'Close OCTAVA and ImageJ', 'Cancel'}, 'DefaultOption', 2, 'CancelOption',3);
            if strcmp(sel,'Close OCTAVA and ImageJ')
                 ij.IJ.run("Quit","");    
            end
            
            if ~strcmp(sel,'Cancel')
                delete(app)
            end
            
        end

        % Menu selected function: ClearPlotsMenu
        function ClearPlotsMenuSelected(app, event)
            %Clear all plots 
            cla(app.inputImageAxes);
            cla(app.BinaryImageAxes);
            cla(app.SkelImageAxes);
            cla(app.OverlayImageAxes);
            cla(app.DiamsAxes);
            cla(app.LengthsAxes);
            cla(app.TortsAxes);
            axis(app.OverlayImageAxes,'on')
            
            %Reset display for caluclulated biomarkers
            app.VesselareadensityLabel.Text = 'Vessel area density = '; 
            app.VessellengthdensityLabel.Text = 'Vessel length density = ';
            app.TotalvessellengthLabel.Text = 'Total velles length = ';
            app.MeandiameterLabel.Text = 'Mean diameter = '  ;
            app.MediandiameterLabel.Text = 'Median diameter = ';
            app.BranchpointdensityLabel.Text = 'Branchpoint density = ';
            app.FractaldimensionLabel.Text = 'Fractal dimension = ';
            app.MeantortuosityLabel.Text = 'Mean tortuosity = ';
            
            %Reset ready label
            app.ReadyLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0 1 0];
        end

        % Menu selected function: SaveHistogramDataMenu
        function SaveHistogramDataMenuSelected(app, event)
            savedir = uigetdir(app.lastPath);  
            if savedir ~=0
                app.lastPath=savedir;
            end
            
            t = datestr(datetime);
            t = erase(t,':')';
            
            name = strcat(savedir, erase(app.FileLabelName,' '), '_HistogramData_', t', '.csv');
            histData.diams = app.biomarkers.Diams;
            histData.torts = app.biomarkers.Tort;
            histData.lengths = app.biomarkers.Lengths;
            
            lmax = max([length(histData.diams), length(histData.torts), length(histData.lengths)]);
            
            if length(histData.diams)<lmax
                l = lmax-length(histData.diams);
                histData.diams(length(histData.diams)+1:lmax) = ones(1,l)*NaN;               
            end
            
            if length(histData.torts)<lmax
                l = lmax-length(histData.torts);
                histData.torts(length(histData.torts)+1:lmax) = ones(1,l)*NaN;               
            end
            
           if length(histData.lengths)<lmax
                l = lmax-length(histData.lengths);
                histData.lengths(length(histData.lengths)+1:lmax) = ones(1,l)*NaN;               
            end
            
            datatable = struct2table(histData);
            
            writetable(datatable,name);

                
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            if isempty(app.inputImage)
                warndlg('You did not choose an image file') ; 
           else
            prompt = {'x pixels:', 'y pixels'};
            dlgtitle = 'New image size';
            [Ny,Nx] = size(app.inputImage);
            definput = {num2str(Nx), num2str(Ny)};
            newsize = inputdlg(prompt,dlgtitle,[1 35],definput);
            if ~isempty(newsize)
                app.inputImage = imresize(app.inputImage,[str2double(newsize{2}), str2double(newsize{1})]);
                imagesc( app.inputImage, 'Parent', app.inputImageAxes);
            end
            app.fullImSizePix = size(app.inputImage); %Changing the size changes the pix/mm 
           end
        end

        % Button pushed function: ImagesubsetButton
        function ImagesubsetButtonPushed(app, event)
             if isempty(app.inputImage)
                warndlg('You did not choose an image file') ;
            else
                ne = newline;
                fid = uifigure('Name','Select subregion of interest','NumberTitle','off',"Color",[0.55,0.75,0.88]);
                imaxs = uiaxes("Parent",fid,'Position',[120,20,400,400]);
                imagesc(app.inputImage, 'Parent',imaxs)
                imaxs.Colormap = gray;
                [Ny,Nx] = size(app.inputImage);
                imaxs.XLim = [0 Nx];
                imaxs.YLim = [0 Ny];
                imaxs.Title.String = 'Please press start to select ROI';
                imaxs.Title.FontSize = 16;
                donebutton = uibutton(fid,"push",'Text','Done','Position',[30 60 60 20]);
                cancelbutton = uibutton(fid,"push",'Text','Cancel','Position',[30 10 60 20]);
                roiSizebutton = uibutton(fid,"push",'Text','Get ROI size', 'Position', [20 135 80 20]);
                startbutton = uibutton(fid,"push",'Text','Start','Position',[30 295 60 20]);
                clearbutton = uibutton(fid,"push",'Text','Clear','Position',[30 35 60 20]);
                sizelabel = uilabel(fid,'Position',[20,160,80,40],"Text",'ROI size',"BackgroundColor",[1 1 1]);
                roiTypGrp = uibuttongroup(fid, 'Position', [20 320  80 60 ]);
                roi1 = uiradiobutton(roiTypGrp,"Text",'Rectangle','Position',[5 30 80 20]);
                uiradiobutton(roiTypGrp,"Text",'Round','Position',[5 10 60 20]);
                resizeCheckBox = uicheckbox(fid,"Text",['Use ROI size' ne 'as image size?'],"Position",[15 175 100 80]);
                realSizeField = uieditfield(fid, 'numeric',"Position",[20,245,80,20],"Value",5);
                uilabel(fid,'Position',[20,253,100,40],"Text",'Img width (mm)');
                pixmmswitch = uiswitch(fid, 'slider',"Position",[40 110 80 20],"Items",{'pix','mm'});
                
                
                roiTypGrp.SelectionChangedFcn = {@roiselection,roi1}; 
                startbutton.ButtonPushedFcn = {@startButtonPushed};
                donebutton.ButtonPushedFcn = {@doneButtonPushed,fid,app,resizeCheckBox};
                cancelbutton.ButtonPushedFcn = {@cancelcallback, fid}; 
                roiSizebutton.ButtonPushedFcn = {@roiSizeCallback,fid,sizelabel,realSizeField,pixmmswitch,Nx};
                clearbutton.ButtonPushedFcn = {@clearButtonPushed};
                
                
                roishape = 'rect';
                h_rect = [];

            end
                
                function cancelcallback(~,~,fid)
                    close(fid)
                end


            function app = doneButtonPushed(~,~,fid,app,resizeCheckBox)
               if strcmp(roishape,'rect')
                    pos_rect = round(h_rect.Position);
                    pos_rect(pos_rect<1) =1; 
                    [y,~] = size(app.inputImage);
                    pos_rect(pos_rect>(y)) =y;
                    app.inputImage = app.inputImage(pos_rect(2) + (0:pos_rect(4)), pos_rect(1) + (0:pos_rect(3)));
                    if resizeCheckBox.Value
                        app.fullImSizePix = pos_rect(3);
                    end
               else
                    t = h_rect.createMask;
                    app.inputImage = app.inputImage.*t;
                    Cx = round(h_rect.Center(1));
                    Cy = round(h_rect.Center(2));
                    r = round(h_rect.Radius);
                    app.inputImage = app.inputImage(Cy-r:Cy+r,Cx-r:Cx+r);
                    if resizeCheckBox.Value
                        app.fullImSizePix = h_rect.Radius*2;
                    end
               end
               
               app.imgShape = roishape;               

               close(fid)    
               imagesc( app.inputImage, 'Parent', app.inputImageAxes);
           end
           
            function  roiselection(~,~,roi1)
                if roi1.Value
                     roishape = 'rect';
                else    
                    roishape = 'round';
                end
                
            end
            
            function roiSizeCallback(~,~,~,sizelabel,realSizeField,pixmmswitch,Nx)
                if ~isempty(h_rect)
                    c = newline; 
                    if strcmp(pixmmswitch.Value,'pix')
                        if strcmp(roishape,'rect')
                            pos_rect = round(h_rect.Position);
                            sizelabel.Text = ['x = ' num2str(pos_rect(3)) ' pixels'  c 'y = ' num2str(pos_rect(4)) ' pixels'];
                        else
                            sizelabel.Text = ['r = ' num2str(round(h_rect.Radius)) ' pixels'];
                        end
                    else
                        pix_to_mm = realSizeField.Value/Nx;
                        if strcmp(roishape,'rect')
                            pos_rect = round(h_rect.Position.*pix_to_mm,2);
                            sizelabel.Text = ['x = ' num2str(pos_rect(3)) ' mm'  c 'y = ' num2str(pos_rect(4)) ' mm'];
                        else
                            sizelabel.Text = ['r = ' num2str(round(h_rect.Radius*pix_to_mm,2)) ' mm'];
                        end
                    end
                        
                else
                    sizelabel.Text = 'ROI not found';
                end
            end
            
            function startButtonPushed(~,~)
                if strcmp(roishape,'rect')
                    h_rect = drawrectangle(imaxs);
                else
                    h_rect = drawcircle(imaxs);
                end
            end
            
            function clearButtonPushed(~,~)
                delete(h_rect)
                h_rect = [];
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OCTAVAUIFigure = uifigure('Visible', 'off');
            app.OCTAVAUIFigure.Name = 'OCTAVA';
            app.OCTAVAUIFigure.CloseRequestFcn = createCallbackFcn(app, @OCTAVAUIFigureCloseRequest, true);
            app.SavingMenu = uimenu(app.OCTAVAUIFigure);
            app.SavingMenu.Text = 'Saving';
            app.SaveBinaryMapMenu = uimenu(app.SavingMenu);
            app.SaveBinaryMapMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBinaryMapMenuSelected, true);
            app.SaveBinaryMapMenu.Text = 'Save Binary Map';
            app.SaveMetricsasmatFileMenu = uimenu(app.SavingMenu);
            app.SaveMetricsasmatFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMetricsasmatFileMenuSelected, true);
            app.SaveMetricsasmatFileMenu.Text = 'Save Metrics as mat File';
            app.SaveHistogramDataMenu = uimenu(app.SavingMenu);
            app.SaveHistogramDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveHistogramDataMenuSelected, true);
            app.SaveHistogramDataMenu.Text = 'Save Histogram Data';
            app.SaveSessionMenu = uimenu(app.SavingMenu);
            app.SaveSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessionMenuSelected, true);
            app.SaveSessionMenu.Text = 'Save Session ';
            app.ClearSessionMenu = uimenu(app.SavingMenu);
            app.ClearSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearSessionMenuSelected, true);
            app.ClearSessionMenu.Text = 'Clear Session';
            app.ImageJMenu = uimenu(app.OCTAVAUIFigure);
            app.ImageJMenu.Text = 'ImageJ';
            app.StartImageJMenu = uimenu(app.ImageJMenu);
            app.StartImageJMenu.MenuSelectedFcn = createCallbackFcn(app, @StartImageJMenuSelected, true);
            app.StartImageJMenu.Text = 'Start ImageJ';
            app.QuitImageJMenu = uimenu(app.ImageJMenu);
            app.QuitImageJMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitImageJMenuSelected, true);
            app.QuitImageJMenu.Text = 'Quit ImageJ';
            app.OCTAVAMenu = uimenu(app.OCTAVAUIFigure);
            app.OCTAVAMenu.Text = 'OCTAVA';
            app.ClearPlotsMenu = uimenu(app.OCTAVAMenu);
            app.ClearPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearPlotsMenuSelected, true);
            app.ClearPlotsMenu.Text = 'Clear Plots';
            app.HelpMenu = uimenu(app.OCTAVAUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ShowOCTAVAhelpMenu = uimenu(app.HelpMenu);
            app.ShowOCTAVAhelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowOCTAVAhelpMenuSelected, true);
            app.ShowOCTAVAhelpMenu.Text = 'Show OCTAVA help';
            app.GridLayout = uigridlayout(app.OCTAVAUIFigure);
            app.LengthsAxes = uiaxes(app.GridLayout);
            title(app.LengthsAxes, 'Lengths')
            xlabel(app.LengthsAxes, 'Length (mm)')
            ylabel(app.LengthsAxes, 'Frequency')
            zlabel(app.LengthsAxes, 'Z')
            app.LengthsAxes.Tag = 'Torts';
            app.TortsAxes = uiaxes(app.GridLayout);
            title(app.TortsAxes, 'Tortuosity')
            xlabel(app.TortsAxes, 'Tortuosity')
            ylabel(app.TortsAxes, 'Frequency')
            zlabel(app.TortsAxes, 'Z')
            app.TortsAxes.Tag = 'Torts';
            app.DiamsAxes = uiaxes(app.GridLayout);
            title(app.DiamsAxes, 'Diameters')
            xlabel(app.DiamsAxes, 'Diameters (\mum)')
            ylabel(app.DiamsAxes, 'Frequency')
            zlabel(app.DiamsAxes, 'Z')
            app.DiamsAxes.Tag = 'Diams';
            app.OverlayImageAxes = uiaxes(app.GridLayout);
            title(app.OverlayImageAxes, 'Overlay')
            app.OverlayImageAxes.XTick = [];
            app.OverlayImageAxes.YTick = [];
            app.SkelImageAxes = uiaxes(app.GridLayout);
            title(app.SkelImageAxes, 'Skeletonized Image')
            app.SkelImageAxes.XTick = [];
            app.SkelImageAxes.YTick = [];
            app.BinaryImageAxes = uiaxes(app.GridLayout);
            title(app.BinaryImageAxes, 'Binarized Image ')
            app.BinaryImageAxes.XTick = [];
            app.BinaryImageAxes.YTick = [];
            app.inputImageAxes = uiaxes(app.GridLayout);
            title(app.inputImageAxes, 'Original OCT MIP')
            app.inputImageAxes.XTick = [];
            app.inputImageAxes.YTick = [];
            app.FileLabel = uilabel(app.GridLayout);
            app.FileLabel.Text = 'Filename';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.StartbatchprocessButton = uibutton(app.GridLayout2, 'push');
            app.StartbatchprocessButton.ButtonPushedFcn = createCallbackFcn(app, @StartbatchprocessButtonPushed, true);
            app.StartbatchprocessButton.Text = {'Start'; 'batch'; 'process'};
            app.SavebinaryimagesCheckBox = uicheckbox(app.GridLayout2);
            app.SavebinaryimagesCheckBox.Text = 'Save binary images';
            app.FiletypeDropDown = uidropdown(app.GridLayout2);
            app.FiletypeDropDown.Items = {'tif', 'png', 'jpg', 'binary mask'};
            app.FiletypeDropDown.Value = 'tif';
            app.FiletypeDropDownLabel = uilabel(app.GridLayout2);
            app.FiletypeDropDownLabel.Text = 'File type';
            app.RawdatafolderButton = uibutton(app.GridLayout2, 'push');
            app.RawdatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @RawdatafolderButtonPushed, true);
            app.RawdatafolderButton.Text = {'Raw data '; 'folder'};
            app.SavefolderButton = uibutton(app.GridLayout2, 'push');
            app.SavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SavefolderButtonPushed, true);
            app.SavefolderButton.Text = {'Save '; 'folder'};
            app.bachfileLabel = uilabel(app.GridLayout2);
            app.bachfileLabel.Text = 'Raw folder';
            app.bachsavefileLabel = uilabel(app.GridLayout2);
            app.bachsavefileLabel.Text = 'Save folder';
            app.BatchProcessingLabel = uilabel(app.GridLayout2);
            app.BatchProcessingLabel.Text = 'Batch Processing';
            app.BinarizationmethodDropDown = uidropdown(app.GridLayout);
            app.BinarizationmethodDropDown.Items = {'Fuzzy means', 'Adaptive thresholding'};
            app.BinarizationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizationmethodDropDown.Value = 'Fuzzy means';
            app.BinarizationmethodDropDownLabel = uilabel(app.GridLayout);
            app.BinarizationmethodDropDownLabel.Text = 'Binarization method';
            app.BinarizeButton = uibutton(app.GridLayout, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Text = 'Binarize';
            app.FrangiBool = uicheckbox(app.GridLayout);
            app.FrangiBool.Text = 'Frangi';
            app.FrangiBool.Value = true;
            app.InvertButton = uibutton(app.GridLayout, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Text = 'Invert';
            app.SkeletonizeButton = uibutton(app.GridLayout, 'push');
            app.SkeletonizeButton.ButtonPushedFcn = createCallbackFcn(app, @SkeletonizeButtonPushed, true);
            app.SkeletonizeButton.Text = 'Skeletonize ';
            app.ManualcurationCheckBox = uicheckbox(app.GridLayout);
            app.ManualcurationCheckBox.Text = 'Manual curation';
            app.TwigsizeSliderLabel = uilabel(app.GridLayout);
            app.TwigsizeSliderLabel.Text = 'Twig size ';
            app.TwigsizeSlider = uislider(app.GridLayout);
            app.TwigsizeSlider.Limits = [0 30];
            app.TwigsizeSlider.Value = 10;
            app.BiomarkersButton = uibutton(app.GridLayout, 'push');
            app.BiomarkersButton.ButtonPushedFcn = createCallbackFcn(app, @BiomarkersButtonPushed, true);
            app.BiomarkersButton.Text = 'Generate metrics';
            app.SelectSaveFolderButton_single = uibutton(app.GridLayout, 'push');
            app.SelectSaveFolderButton_single.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveFolderButton_singlePushed, true);
            app.SelectSaveFolderButton_single.Text = 'Select save folder';
            app.savefileLabel_single = uilabel(app.GridLayout);
            app.CloseImageJwindowsButton = uibutton(app.GridLayout, 'push');
            app.CloseImageJwindowsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseImageJwindowsButtonPushed, true);
            app.CloseImageJwindowsButton.Text = 'Close ImageJ windows ';
            app.LoadimageButton = uibutton(app.GridLayout, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.MedfiltCheckBox = uicheckbox(app.GridLayout);
            app.MedfiltCheckBox.Text = {'Med '; 'filt'};
            app.kernelSize = uispinner(app.GridLayout);
            app.kernelSize.Limits = [0 Inf];
            app.kernelSize.Value = 3;
            app.BinsizeforhistogramsLabel = uilabel(app.GridLayout);
            app.BinsizeforhistogramsLabel.Text = 'Bin size for histograms';
            app.binsizeDiams = uispinner(app.GridLayout);
            app.binsizeDiams.Limits = [0 Inf];
            app.binsizeDiams.Value = 10;
            app.binsizeLength = uispinner(app.GridLayout);
            app.binsizeLength.Step = 0.1;
            app.binsizeLength.Limits = [0 Inf];
            app.binsizeLength.Value = 0.1;
            app.binsizeTort = uispinner(app.GridLayout);
            app.binsizeTort.Step = 0.1;
            app.binsizeTort.Limits = [0 Inf];
            app.binsizeTort.Value = 0.1;
            app.ResizeButton = uibutton(app.GridLayout, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.ReadyLamp = uilamp(app.GridLayout);
            app.ReadyLabel = uilabel(app.GridLayout);
            app.ReadyLabel.Text = 'Ready';
            app.ProcessSingleImageLabel = uilabel(app.GridLayout);
            app.ProcessSingleImageLabel.Text = 'Process Single Image ';
            app.ImagesubsetButton = uibutton(app.GridLayout, 'push');
            app.ImagesubsetButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesubsetButtonPushed, true);
            app.ImagesubsetButton.Text = 'Image subset';
            app.Frangisigma_lbSpinnerLabel = uilabel(app.GridLayout);
            app.Frangisigma_lbSpinnerLabel.Text = {'Frangi'; 'sigma_lb'};
            app.Frangisigma_lbSpinner = uispinner(app.GridLayout);
            app.Frangisigma_lbSpinner.Limits = [0 30];
            app.Frangisigma_lbSpinner.RoundFractionalValues = 'on';
            app.Frangisigma_lbSpinner.ValueDisplayFormat = '%d';
            app.Frangisigma_lbSpinner.ValueChangedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.Frangisigma_lbSpinner.Value = 8;
            app.Frangisigma_ubSpinner = uispinner(app.GridLayout);
            app.Frangisigma_ubSpinner.Limits = [0 30];
            app.Frangisigma_ubSpinner.RoundFractionalValues = 'on';
            app.Frangisigma_ubSpinner.ValueDisplayFormat = '%d';
            app.Frangisigma_ubSpinner.Value = 8;
            app.RealImageSizemm = uieditfield(app.GridLayout, 'numeric');
            app.RealImageSizemm.Value = 5;
            app.ImagesizemmLabel = uilabel(app.GridLayout);
            app.ImagesizemmLabel.Text = 'Image size (mm)';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.MetricsLabel = uilabel(app.GridLayout3);
            app.MetricsLabel.Text = 'Metrics';
            app.VesselareadensityLabel = uilabel(app.GridLayout3);
            app.VesselareadensityLabel.Text = 'Vessel area density = ';
            app.VessellengthdensityLabel = uilabel(app.GridLayout3);
            app.VessellengthdensityLabel.Text = 'Vessel length density = ';
            app.MeandiameterLabel = uilabel(app.GridLayout3);
            app.MeandiameterLabel.Text = 'Mean diameter = ';
            app.MediandiameterLabel = uilabel(app.GridLayout3);
            app.MediandiameterLabel.Text = 'Median diameter = ';
            app.BranchpointdensityLabel = uilabel(app.GridLayout3);
            app.BranchpointdensityLabel.Text = 'Branchpoint density = ';
            app.TotalvessellengthLabel = uilabel(app.GridLayout3);
            app.TotalvessellengthLabel.Text = 'Total vessel length = ';
            app.FractaldimensionLabel = uilabel(app.GridLayout3);
            app.FractaldimensionLabel.Text = 'Fractal dimension = ';
            app.MeantortuosityLabel = uilabel(app.GridLayout3);
            app.MeantortuosityLabel.Text = 'Mean tortuosity = ';
            app.Frangisigma_ubSpinnerLabel = uilabel(app.GridLayout);
            app.Frangisigma_ubSpinnerLabel.Text = {'Frangi'; 'sigma_ub'};
            % Show the figure after all components are created
            app.OCTAVAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OCTAVA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OCTAVAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OCTAVAUIFigure)
        end
    end
end